classdef seirgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        R0EditFieldLabel  matlab.ui.control.Label
        R0EditField       matlab.ui.control.NumericEditField
        E0Label           matlab.ui.control.Label
        E0EditField       matlab.ui.control.NumericEditField
        I0Label           matlab.ui.control.Label
        I0EditField       matlab.ui.control.NumericEditField
        SNpopofCanadaEIRMakesureinputtedvalueswontmakethisnegativeLabel  matlab.ui.control.Label
        SubmitButton      matlab.ui.control.Button
        ClearButton       matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: E0EditField
        function E0EditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: I0EditField
        function I0EditFieldValueChanged(app, event)
           
            
        end

        % Value changed function: R0EditField
        function R0EditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            e = app.E0EditField.Value;
            i = app.I0EditField.Value;
            r = app.R0EditField.Value;
            
            function dy = seir(t,y)
                %initial conditions of derivatives (0) + constants
                dy = [0 0 0 0];
                N = 38005238;
                gamma = 1/14;
                r_0 = 2.1;
                beta = gamma.*r_0;
                sigma = 1/5;
                %System of equations - S=y1,E=y2,I=y3,R=y4
                dy(1) = (-beta./N).*y(1).*y(3);
                dy(2) = (beta./N).*y(1).*y(3)-sigma.*y(2);
                dy(3) = sigma.*y(2)-(gamma.*y(3));
                dy(4) = gamma.*y(3);
                %Return column vector
                dy = dy(:);
            end
            
            N = 38005237;
            s = N-r-e-i;
            initial_value = [s e i r];
            tspan = [0 365];
            [t, y] = ode45(@seir, tspan, initial_value);
            plot(app.UIAxes, t,y);
            lgd = legend(app.UIAxes,'S','E','I','R');
            lgd.Location = 'west';
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.E0EditField.Value=0;
            app.I0EditField.Value=0;
            app.R0EditField.Value=0;
            cla(app.UIAxes);
            legend(app.UIAxes,'boxoff');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {224, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.R0EditFieldLabel = uilabel(app.LeftPanel);
            app.R0EditFieldLabel.Text = 'R(0)';
            app.R0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.R0EditField.ValueChangedFcn = createCallbackFcn(app, @R0EditFieldValueChanged, true);
            app.E0Label = uilabel(app.LeftPanel);
            app.E0Label.Text = 'E(0)';
            app.E0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.E0EditField.ValueChangedFcn = createCallbackFcn(app, @E0EditFieldValueChanged, true);
            app.I0Label = uilabel(app.LeftPanel);
            app.I0Label.Text = 'I(0)';
            app.I0EditField = uieditfield(app.LeftPanel, 'numeric');
            app.I0EditField.ValueChangedFcn = createCallbackFcn(app, @I0EditFieldValueChanged, true);
            app.SNpopofCanadaEIRMakesureinputtedvalueswontmakethisnegativeLabel = uilabel(app.LeftPanel);
            app.SNpopofCanadaEIRMakesureinputtedvalueswontmakethisnegativeLabel.Text = {'S=N (pop. of Canada)-E-I-R'; ''; 'Make sure inputted values won''t '; 'make this negative'};
            app.SubmitButton = uibutton(app.LeftPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.ClearButton = uibutton(app.LeftPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'SEIR Model of Covid-19 in Canada')
            xlabel(app.UIAxes, 'Days')
            ylabel(app.UIAxes, 'Population')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 73 146 219 292 365];
            app.UIAxes.XTickLabel = {'0'; '73'; '146'; '219'; '292'; '365'};
            app.UIAxes.YTick = [0 10000000 20000000 30000000 40000000];
            app.UIAxes.YTickLabel = {'0'; '10000000'; '20000000'; '30000000'; '40000000'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = seirgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end